classdef FMCW_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EKF_onoff         matlab.ui.control.Spinner
        Label_11          matlab.ui.control.Label
        serch_serial      matlab.ui.control.Button
        led               matlab.ui.control.Lamp
        on_off            matlab.ui.control.Switch
        GLabel            matlab.ui.control.Label
        SerialPortnumber  matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        isRunning = false;
        ser; % Description
        rar;                %串口读取雷达数据对象
        serialname;         %串口名字
        

        % 多线程相关属性
        futureObj;          % 后台任务对象
        dataQueue;          % 数据队列
        isProcessing = false; % 处理状态标志
        frameCnt ;       % 帧计数器
        chirpIdxShow = 32;  % 显示的chirp索引
        drawEveryN = 1;     % 每N帧更新一次显示
        targetScatter;      % 目标散点图句柄
        maxTargets = 20;    % 最大显示目标数
        targetHistory = []; % 目标历史轨迹
        radarRange = 10;     % 雷达最大检测距离
        radarAngleMin = -90; % 最小角度（度）
        radarAngleMax = 90;  % 最大角度（度）
        colorbarHandle;     % 颜色条句柄
        trajectoryLines  ; % 存储轨迹线句柄
        lastTargetIDs  ; % 上一帧的目标ID列表
        colorIndex ; % 轨迹颜色索引
        colorOrder; % 颜色循环
        maxTrajectoryLength ; % 最大轨迹长度
    end

    % Callbacks that handle component events
    methods (Access = private)

        % 初始化图形显示
        function initializePlots(app)
            % 设置UIAxes为雷达坐标系
            cla(app.UIAxes); % 清除现有内容
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');
        
            % 计算扇形边界
            maxX = app.radarRange * sind(app.radarAngleMax);
            minX = app.radarRange * sind(app.radarAngleMin);
            maxY = app.radarRange * cosd(0);
        
            % 设置坐标轴范围
            xlim(app.UIAxes, [minX-0.5, maxX+0.5]);
            ylim(app.UIAxes, [-0.5, maxY+0.5]);
            xlabel(app.UIAxes, 'X坐标 (米)');
            ylabel(app.UIAxes, 'Y坐标 (米)');
            title(app.UIAxes, '雷达目标检测 (扇形视图)');
        
            % 设置坐标轴比例为等比
            axis(app.UIAxes, 'equal');
        
            % 绘制雷达扇形区域
            app.drawRadarSector();
        
            % 创建目标散点图句柄 - 只创建一次
            app.targetScatter = scatter(app.UIAxes, NaN, NaN, 100, 'r', 'filled', ...
                'DisplayName', '检测目标', 'MarkerEdgeColor', 'k');
            
            % 添加雷达位置标记
            scatter(app.UIAxes, 0, 0, 150, 'b', '^', 'filled', ...
                'DisplayName', '雷达位置', 'MarkerEdgeColor', 'k', 'LineWidth', 2);
            
        
            % 显示图例
            legend(app.UIAxes, 'show', 'Location', 'northeast');
        
            % 初始化轨迹相关数据结构
            app.targetHistory = containers.Map('KeyType', 'double', 'ValueType', 'any'); % 按ID存储目标历史
            app.trajectoryLines = containers.Map('KeyType', 'double', 'ValueType', 'any'); % 存储轨迹线句柄
            app.lastTargetIDs = []; % 上一帧的目标ID列表
            app.colorIndex = 1; % 轨迹颜色索引
            app.colorOrder = get(app.UIAxes, 'ColorOrder'); % 颜色循环
            app.maxTrajectoryLength = 20; % 最大轨迹长度
        end
     % 绘制雷达扇形区域
        function drawRadarSector(app)
            % 绘制距离圈
            theta = linspace(deg2rad(app.radarAngleMin), deg2rad(app.radarAngleMax), 100);
    
            % 绘制同心圆弧（距离圈）
            for r = [2, 4, 6, 8]
                if r <= app.radarRange
                    x_arc = r * sin(theta);
                    y_arc = r * cos(theta);
                    plot(app.UIAxes, x_arc, y_arc, '--', 'Color', [0.7 0.7 0.7], ...
                        'LineWidth', 0.8, 'HandleVisibility', 'off');
    
                    % 在弧线中点添加距离标签
                    midAngle = (app.radarAngleMin + app.radarAngleMax) / 2;
                    labelX = r * sind(midAngle) * 1.1;
                    labelY = r * cosd(midAngle) * 1.1;
                    text(app.UIAxes, labelX, labelY, sprintf('%dm', r), ...
                        'FontSize', 8, 'Color', [0.5 0.5 0.5], ...
                        'HorizontalAlignment', 'center', 'HandleVisibility', 'off');
                end
            end
    
            % 绘制角度线
            for angle = [app.radarAngleMin, -30, 0, 30, app.radarAngleMax]
                x_line = [0, app.radarRange * sind(angle)];
                y_line = [0, app.radarRange * cosd(angle)];
    
                if angle == 0
                    % 中心线用实线
                    plot(app.UIAxes, x_line, y_line, '-', 'Color', [0.5 0.5 0.5], ...
                        'LineWidth', 1, 'HandleVisibility', 'off');
                else
                    % 其他角度线用虚线
                    plot(app.UIAxes, x_line, y_line, ':', 'Color', [0.7 0.7 0.7], ...
                        'LineWidth', 0.8, 'HandleVisibility', 'off');
                end
    
                % 添加角度标签
                labelR = app.radarRange * 0.9;
                labelX = labelR * sind(angle);
                labelY = labelR * cosd(angle);
                text(app.UIAxes, labelX, labelY, sprintf('%d°', angle), ...
                    'FontSize', 8, 'Color', [0.5 0.5 0.5], ...
                    'HorizontalAlignment', 'center', 'HandleVisibility', 'off');
            end
    
            % 绘制扇形边界
            % 左边界
            x_left = [0, app.radarRange * sind(app.radarAngleMin)];
            y_left = [0, app.radarRange * cosd(app.radarAngleMin)];
            plot(app.UIAxes, x_left, y_left, 'k-', 'LineWidth', 2, 'HandleVisibility', 'off');
    
            % 右边界
            x_right = [0, app.radarRange * sind(app.radarAngleMax)];
            y_right = [0, app.radarRange * cosd(app.radarAngleMax)];
            plot(app.UIAxes, x_right, y_right, 'k-', 'LineWidth', 2, 'HandleVisibility', 'off');
    
            % 外弧边界
            theta_boundary = linspace(deg2rad(app.radarAngleMin), deg2rad(app.radarAngleMax), 50);
            x_boundary = app.radarRange * sin(theta_boundary);
            y_boundary = app.radarRange * cos(theta_boundary);
            plot(app.UIAxes, x_boundary, y_boundary, 'k-', 'LineWidth', 2, 'HandleVisibility', 'off');
    
            % 填充扇形区域（半透明）
            theta_fill = linspace(deg2rad(app.radarAngleMin), deg2rad(app.radarAngleMax), 50);
            x_fill = [0, app.radarRange * sin(theta_fill), 0];
            y_fill = [0, app.radarRange * cos(theta_fill), 0];
            fill(app.UIAxes, x_fill, y_fill, [0.5 0.8 1], 'FaceAlpha', 0.3, ...
                'EdgeColor', 'none', 'HandleVisibility', 'off');
        end
        function updateTargetDisplay(app, targets)
            % 如果没有目标，快速返回
            if isempty(targets)
                if ~isnan(app.targetScatter.XData(1)) % 只有当前有数据时才清空
                    set(app.targetScatter, 'XData', NaN, 'YData', NaN);
                end
                return;
            end
            
            % 处理targets格式 [track_id x y]
            if app.EKF_onoff.Value
                x = targets(:, 2);
                y = targets(:, 3);
                % 更新散点图        
                if ~any(targets)
                    set(app.targetScatter, 'XData', NaN, 'YData', NaN);
                    return;
                end                
                set(app.targetScatter, 'XData', x, 'YData', y);
                return;
            else
                track_ids = targets(:, 1);
                x = targets(:, 2);
                y = targets(:, 3);
            end
            % 计算range和angle用于过滤
            range = sqrt(x.^2 + y.^2);
            theta = atan2d(x, y); % 使用atan2d得到角度（度）
            
            % 过滤目标 - 只保留在雷达扇区内的目标
            validIdx = range <= app.radarRange & ...
                       range > 0.5 & ...
                       theta >= app.radarAngleMin & ...
                       theta <= app.radarAngleMax & ...
                       y >= 0;
            
            if ~any(validIdx)
                set(app.targetScatter, 'XData', NaN, 'YData', NaN);
                return;
            end
            
            % 获取有效的目标位置和ID
            validX = x(validIdx);
            validY = y(validIdx);
            validIDs = track_ids(validIdx);
            
            % 限制目标数量
            if length(validX) > app.maxTargets
                % 使用range排序
                validRange = range(validIdx);
                [~, sortIdx] = sort(validRange);
                keepIdx = sortIdx(1:app.maxTargets);
                
                validX = validX(keepIdx);
                validY = validY(keepIdx);
                validIDs = validIDs(keepIdx);
            end
            
            % 更新散点图
            set(app.targetScatter, 'XData', validX, 'YData', validY);
            
            % 根据EKF_onoff.Value决定是否更新轨迹
            % 当值为0时显示轨迹，值为1时不显示
            if ~app.EKF_onoff.Value
                % 获取有效的目标位置
                validPositions = [validX, validY];
                app.updateTrajectory(validPositions, validIDs);
            else
                % 如果不显示轨迹，清除所有已有轨迹
                app.clearAllTrajectories();
            end
        end
        
        % 清除所有轨迹
        function clearAllTrajectories(app)
            % 清除所有轨迹线
            ids = keys(app.trajectoryLines);
            for i = 1:length(ids)
                id = ids{i};
                if isvalid(app.trajectoryLines(id))
                    delete(app.trajectoryLines(id));
                end
            end
            
            % 重置轨迹数据结构
            app.targetHistory = containers.Map('KeyType', 'double', 'ValueType', 'any');
            app.trajectoryLines = containers.Map('KeyType', 'double', 'ValueType', 'any');
            app.lastTargetIDs = [];
        end
        
        % 更新目标轨迹
        function updateTrajectory(app, currentPositions, targetIDs)
            % 为每个目标更新轨迹
            for i = 1:length(targetIDs)
                id = targetIDs(i);
                currentPos = currentPositions(i, :);
                
                % 如果是新目标，初始化轨迹
                if ~isKey(app.targetHistory, id)
                    % 创建新轨迹
                    app.targetHistory(id) = currentPos;
                    
                    % 为新轨迹分配颜色
                    colorIdx = mod(app.colorIndex - 1, size(app.colorOrder, 1)) + 1;
                    app.colorIndex = app.colorIndex + 1;
                    
                    % 创建轨迹线句柄
                    app.trajectoryLines(id) = plot(app.UIAxes, currentPos(1), currentPos(2), ...
                        '-', 'Color', app.colorOrder(colorIdx, :), 'LineWidth', 1.5, ...
                        'HandleVisibility', 'off');
                else
                    % 获取历史轨迹
                    history = app.targetHistory(id);
                    
                    % 添加当前位置到历史
                    history = [history; currentPos];
                    
                    % 限制轨迹长度
                    if size(history, 1) > app.maxTrajectoryLength
                        history = history(end-app.maxTrajectoryLength+1:end, :);
                    end
                    
                    % 更新历史
                    app.targetHistory(id) = history;
                    
                    % 更新轨迹线
                    if isvalid(app.trajectoryLines(id))
                        set(app.trajectoryLines(id), 'XData', history(:,1), 'YData', history(:,2));
                    end
                end
            end
            
            % 清理不再存在的目标轨迹
            oldIDs = keys(app.trajectoryLines);
            for i = 1:length(oldIDs)
                id = oldIDs{i};
                if ~ismember(id, targetIDs) && isvalid(app.trajectoryLines(id))
                    % 目标消失一段时间后，删除轨迹
                    % 可以根据需要添加延迟删除逻辑
                    delete(app.trajectoryLines(id));
                    remove(app.trajectoryLines, id);
                    remove(app.targetHistory, id);
                end
            end
            
            % 更新上一帧目标ID
            app.lastTargetIDs = targetIDs;
        end
        
        % 启动后台数据处理
        function startBackgroundProcessing(app)
            if app.isProcessing
                return;
            end
        
            app.isProcessing = true;
            app.frameCnt = 0;
        
            % 创建数据队列
            app.dataQueue = parallel.pool.DataQueue;
            % 设置数据接收回调
            afterEach(app.dataQueue, @app.updateGUIWithRadarData);
        
            % 准备传递给工作进程的参数
            radarParams = setRadarParameters();
            radarParams.port = app.serialname(app.SerialPortnumber.Value);

            radarParams.reset = app.EKF_onoff.Value;   %选择是否使用EKF进行目标轨迹预测，1清零，0工作
        
        
            % 启动后台任务
            app.futureObj = parfeval(@processRadarDataBackground, 0, ...
                app.dataQueue, radarParams);
        end
        
        % 停止后台处理
        function stopBackgroundProcessing(app)
            app.isProcessing = false;
        
            % 取消后台任务
            if ~isempty(app.futureObj) && isvalid(app.futureObj)
                cancel(app.futureObj);
                app.futureObj = [];
            end
        
            % 清理数据队列
            if ~isempty(app.dataQueue)
                app.dataQueue = [];
            end
        end
        
        % 更新GUI显示
        function updateGUIWithRadarData(app, data)
            if ~app.isProcessing || isempty(data)
                return;
            end
        
            % 处理错误信息

            disp("test");
            % 更新帧计数
            app.frameCnt = data.frameCnt;      
            % 检查是否需要更新显示
            if mod(app.frameCnt, app.drawEveryN) == 0
                
                % 处理目标检测结果
                if isfield(data, 'targets') && ~isempty(data.targets)
                    app.updateTargetDisplay(data.targets);
                else
                    % 如果没有检测到目标，清空显示
                    set(app.targetScatter, 'XData', NaN, 'YData', NaN);
                end
                assignin("base","targets",data.targets)
                % 更新标题
                numTargets = 0;
                if isfield(data, 'targets')
                    numTargets = size(data.targets, 1);
                end
                disp(data.processTime);
                title(app.UIAxes, sprintf('Frame %d | 检测目标: %d | Range: %.1fm | FOV: %d°~%d° | %s', ...
                    data.frameCnt, numTargets, app.radarRange, app.radarAngleMin, app.radarAngleMax, ...
                    conditional(app, '无轨迹', '显示轨迹')));
                    
                drawnow limitrate;
            end
        end
        
        % 辅助函数：条件文本返回
        function text = conditional(app, trueText, falseText)
            if app.EKF_onoff.Value == 1
                text = trueText;
            else
                text = falseText;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化串口列表
            app.serialname = serialportlist;
            Devices = app.serialname;
            num = length(Devices);
            items = cell(1,num);
            itemsdata = zeros(1,num);
            for i = 1:num
                items{i} = [char(Devices(i))];
                itemsdata(i) = i;
            end
            app.SerialPortnumber.Items = items;
            app.SerialPortnumber.ItemsData = itemsdata;

            % 初始化图形显示
            app.initializePlots();

            % 初始化并行池
            if isempty(gcp('nocreate'))
                try
                    parpool('local', 2);
                catch ME
                    warning(ME.identifier,'无法创建并行池: %s', ME.message);
                end
            end
        end

        % Button pushed function: serch_serial
        function serch_serialPushed(app, event)
            app.serialname = serialportlist; %查找串口
            Devices = app.serialname;
            num = length(Devices); % 传回设备个数信息
            items = cell(1,num);
            itemsdata = zeros(1,num);
            for i = 1:num
                items{i} = [char(Devices(i))];
                itemsdata(i) = i;
            end
            app.SerialPortnumber.Items = items;
            app.SerialPortnumber.ItemsData = itemsdata;
        end

        % Value changed function: on_off
        function on_offValueChanged(app, event)
            value = app.on_off.Value; %串口开关回到函数
            if(value == "ON")
                
                serName = app.serialname(app.SerialPortnumber.Value);
                app.led.Color=[0,1,0];  %绿色，灯亮
                %                 app.rar = read_Raw_serial(serName, app.chirpsPerFrame);
                % 启动后台数据处理
                app.startBackgroundProcessing();
            else
                % 停止后台处理
                app.stopBackgroundProcessing();
                if ~isempty(app.rar)
                    app.rar.close();
                end
                app.led.Color=[1,1,1];  %白色，灯灭
            end
            assignin("base","led_color",app.led.Color)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopBackgroundProcessing();
            delete(app)
        end

        % Callback function
        function update_paramButtonPushed(app, event)
            app.stopBackgroundProcessing();
            pause(0.3);
            app.startBackgroundProcessing();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-6.93 6.93];
            app.UIAxes.YLim = [0 8];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '串口号';
            app.SerialPortnumber = uidropdown(app.UIFigure);
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = {'基于24G毫米波雷达的室内多人员检测跟踪系统'; ''};
            app.on_off = uiswitch(app.UIFigure, 'slider');
            app.on_off.Items = {'OFF', 'ON'};
            app.on_off.ValueChangedFcn = createCallbackFcn(app, @on_offValueChanged, true);
            app.on_off.Value = 'OFF';
            app.led = uilamp(app.UIFigure);
            app.serch_serial = uibutton(app.UIFigure, 'push');
            app.serch_serial.ButtonPushedFcn = createCallbackFcn(app, @serch_serialPushed, true);
            app.serch_serial.Text = '查找串口';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '轨迹预测开关 0:开 1:关';
            app.EKF_onoff = uispinner(app.UIFigure);
            app.EKF_onoff.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FMCW_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end